classdef LineIntegralGUI__FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VectorFieldLabel               matlab.ui.control.Label
        ParametrizedCurveLabel         matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        IntergalValueEditField_2       matlab.ui.control.NumericEditField
        IntergalValueEditField_2Label  matlab.ui.control.Label
        CEditField                     matlab.ui.control.EditField
        CEditFieldLabel                matlab.ui.control.Label
        BEditField                     matlab.ui.control.EditField
        BEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.EditField
        AEditFieldLabel                matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        HowtoUseTextArea               matlab.ui.control.TextArea
        HowtoUseTextAreaLabel          matlab.ui.control.Label
        UpperBoundEditField            matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel       matlab.ui.control.Label
        LowerBoundEditField            matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel       matlab.ui.control.Label
        ZcomponentEditField            matlab.ui.control.EditField
        ZcomponentEditFieldLabel       matlab.ui.control.Label
        PlotVectorFieldButton          matlab.ui.control.Button
        YcomponentEditField            matlab.ui.control.EditField
        YcomponentEditFieldLabel       matlab.ui.control.Label
        XcomponentEditFieldLabel       matlab.ui.control.Label
        XcomponentEditField            matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotVectorFieldButton
        function PlotVectorFieldButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%% CREATE THE BOX AND PLOT VECTOR FIELDS %%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Clear the plot before plotting a new one
            cla(app.UIAxes, 'reset')
            
            % Get the lower and the upper bound
            lower_bound = app.LowerBoundEditField.Value;
            upper_bound = app.UpperBoundEditField.Value;
            
            % Get the parametrization of the line
            A_path = convertCharsToStrings(app.AEditField.Value);
            B_path = convertCharsToStrings(app.BEditField.Value);
            C_path = convertCharsToStrings(app.CEditField.Value);
            
            % We want to make a box that fix the line in
            % Therefore, we need to find the lower/upper bound for the line
            % in x, y, and z coordinates
            lower_bound_x = +Inf; lower_bound_y = +Inf; lower_bound_z = +Inf;
            upper_bound_x = -Inf; upper_bound_y = -Inf; upper_bound_z = -Inf;
            
            % Loop through around 100 t-value from lower to upper bound
            % Evaluate the value of each coordinate at that t-value
            % Get the lowest and the largest
            for t = lower_bound:(upper_bound-lower_bound)/100:upper_bound
                x_value_at_t = eval(A_path);
                if x_value_at_t < lower_bound_x
                    lower_bound_x = x_value_at_t;
                end
                if x_value_at_t > upper_bound_x
                    upper_bound_x = x_value_at_t;
                end
                y_value_at_t = eval(B_path);
                if y_value_at_t < lower_bound_y
                    lower_bound_y = y_value_at_t;
                end
                if y_value_at_t > upper_bound_y
                    upper_bound_y = y_value_at_t;
                end
                if (C_path ~= "")
                    z_value_at_t = eval(C_path);
                    if z_value_at_t < lower_bound_z
                        lower_bound_z = z_value_at_t;
                    end
                    if z_value_at_t > upper_bound_z
                        upper_bound_z = z_value_at_t;
                    end
                end
            end
            
            % Set the number of vector to be plot for each axes
            % For example, 5 means that there is 5x5x5 = 125 vectors
            % plotted in the xyz coordiantes
            numbers_of_vector = 5;
            
            % constructing the range for the rectangular box with meshgrid
            x = lower_bound_x:(upper_bound_x - lower_bound_x)/(numbers_of_vector-1):upper_bound_x;
            y = lower_bound_y:(upper_bound_y - lower_bound_y)/(numbers_of_vector-1):upper_bound_y;
            z = lower_bound_z:(upper_bound_z - lower_bound_z)/(numbers_of_vector-1):upper_bound_z;

            [matrix_x ,matrix_y, matrix_z] = meshgrid(x,y,z);
            
            % Get the input for the vector field
            % Convert User's input from char to string in the text field 
            x_expr = convertCharsToStrings(app.XcomponentEditField.Value);
            y_expr = convertCharsToStrings(app.YcomponentEditField.Value);
            z_expr = convertCharsToStrings(app.ZcomponentEditField.Value);

            if (x_expr ~= "")
                % Create an empty array for holding the value
                [rows_x, cols_x, heights_x] = size(matrix_x);
                matrix_x_output = zeros(rows_x, cols_x, heights_x);
                % Loop through each element in the array
                for i = 1:rows_x
                    for j = 1:cols_x
                        for k = 1:heights_x
                            % Evaluate the value and fill up the placeholder 3D array
                            x = matrix_x(i,j,k);
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end
                    end
                end
            end

            if (y_expr ~= "")
                [rows_y, cols_y, heights_y] = size(matrix_y);
                matrix_y_output = zeros(rows_y, cols_y, heights_y);
                for i = 1:rows_y
                    for j = 1:cols_y
                        for k = 1:heights_y
                            % Evaluate the value and fill up the placeholder 3D array
                            y = matrix_y(i,j,k);
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end
                    end
                end
            end

            if (z_expr ~= "")
                [rows_z, cols_z, heights_z] = size(matrix_z);
                matrix_z_output = zeros(rows_z, cols_z, heights_z);
                for i = 1:rows_z
                    for j = 1:cols_z
                        for k = 1:heights_z
                            % Evaluate the value and fill up the placeholder 3D array
                            z = matrix_z(i,j,k);
                            matrix_z_output(i,j,k) = eval(z_expr);
                        end
                    end
                end
            end

            % Plot
            if (z_expr ~= "")
                % 3D quiver plot with red color
                quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
                    matrix_x_output, matrix_y_output, matrix_z_output, 'Color', 'b', 'LineWidth', 1.5);
            else
                % 2D quiver plot with red color
                quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 'b', 'LineWidth', 1.5);
            end

            hold(app.UIAxes, 'on');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% PLOT LINE AND GET INT VALUE %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Prompt the user for input functions A, B, C (parameterized curve)
            A_input = app.AEditField.Value;
            B_input = app.BEditField.Value;
            C_input = app.CEditField.Value;

            % Prompt the user for input functions P, Q, R (vector field)
            P_input = app.XcomponentEditField.Value;
            Q_input = app.YcomponentEditField.Value;
            R_input = app.ZcomponentEditField.Value;

            % Prompt the user for bounds and grid size
            t_min = app.LowerBoundEditField.Value;
            t_max = app.UpperBoundEditField.Value;

            if (R_input == "")
                % Define the parameterized curve (replace with your expressions)
                A = str2func(['@(t) ' A_input]);
                B = str2func(['@(t) ' B_input]);

                % Convert input strings to+ function handles for the vector field
                P = str2func(['@(x, y) ' P_input]);
                Q = str2func(['@(x, y) ' Q_input]);

                % Define the parameter range
                t = linspace(t_min, t_max, 100);

                % Evaluate the vector field along the parameterized curve
                x_curve = A(t);
                y_curve = B(t);

                dx = gradient(A(t), t);
                dy = gradient(B(t), t);

                integrand = P(x_curve, y_curve) .* dx + ...
                    Q(x_curve, y_curve) .* dy;

                line_integral_value = trapz(t, integrand);

                app.IntergalValueEditField_2.Value = line_integral_value;

                % Keep the current plot and add the parameterized curve
                plot(app.UIAxes, x_curve, y_curve, 'r', 'LineWidth', 3);
                hold(app.UIAxes, 'off');

            else
                % Convert input strings to function handles for the parameterized curve
                A = str2func(['@(t) ' A_input]);
                B = str2func(['@(t) ' B_input]);
                C = str2func(['@(t) ' C_input]);

                % Convert input strings to+ function handles for the vector field
                P = str2func(['@(x, y, z) ' P_input]);
                Q = str2func(['@(x, y, z) ' Q_input]);
                R = str2func(['@(x, y, z) ' R_input]);

                % Create a parameter range for the curve
                t = linspace(t_min, t_max, 100);

                % Evaluate the parameterized curve at each point in the parameter range
                x_curve = A(t);
                y_curve = B(t);
                z_curve = C(t);

                % Compute the line integral using the parameterized curve and vector field
                dx = gradient(A(t), t);
                dy = gradient(B(t), t);
                dz = gradient(C(t), t);

                integrand = P(x_curve, y_curve, z_curve) .* dx + ...
                    Q(x_curve, y_curve, z_curve) .* dy + ...
                    R(x_curve, y_curve, z_curve) .* dz;

                line_integral_value = trapz(t, integrand);

                app.IntergalValueEditField_2.Value = line_integral_value;

                % Plot the 3D parameterized curve on UIAxes
                plot3(app.UIAxes, x_curve, y_curve, z_curve, 'r', 'LineWidth', 3);
                hold(app.UIAxes, 'off');
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector Field Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.XcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.XcomponentEditFieldLabel.Text = 'X-component';
            app.YcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.YcomponentEditFieldLabel.Text = 'Y-component';
            app.YcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.PlotVectorFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotVectorFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVectorFieldButtonPushed, true);
            app.PlotVectorFieldButton.Text = 'Plot Vector Field';
            app.ZcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.ZcomponentEditFieldLabel.Text = 'Z-component';
            app.ZcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerBoundEditField.Value = -3;
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperBoundEditField.Value = 3;
            app.HowtoUseTextAreaLabel = uilabel(app.UIFigure);
            app.HowtoUseTextAreaLabel.Text = 'How to Use';
            app.HowtoUseTextArea = uitextarea(app.UIFigure);
            app.HowtoUseTextArea.Value = {'1. Always write out all the expression, e.g. x*y.'; '2. Add a dot "." before every operations, e.g. x.^2 or 1./y or t.^2'; '3. Put only t-term in ABC and put only x y z variable in X,Y,Z-components. '; '4. To plot 2D fields and lines, leave the C box and Z-component blank.'; '4. Clear Button helps you clear all the drawing the the plot. '; '5. Press Exit to exit the app'};
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'text');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'text');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'text');
            app.IntergalValueEditField_2Label = uilabel(app.UIFigure);
            app.IntergalValueEditField_2Label.Text = 'Intergal Value';
            app.IntergalValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ParametrizedCurveLabel = uilabel(app.UIFigure);
            app.ParametrizedCurveLabel.Text = 'Parametrized Curve';
            app.VectorFieldLabel = uilabel(app.UIFigure);
            app.VectorFieldLabel.Text = 'Vector Field';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineIntegralGUI__FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end