classdef linear_Huy_April11_v6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TESTTab                         matlab.ui.container.Tab
        PlotCheckButton                 matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        PlotPlaneInR3Button             matlab.ui.control.Button
        PlotColVectorsButton            matlab.ui.control.Button
        SolveAugementedMatrixButton     matlab.ui.control.Button
        DestinationRowEditField         matlab.ui.control.NumericEditField
        DestinationRowEditFieldLabel    matlab.ui.control.Label
        SourceRowEditField              matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label_2  matlab.ui.control.Label
        MultiplyFactorEditField_2       matlab.ui.control.EditField
        MultiplyFactorEditField_2Label  matlab.ui.control.Label
        AddMultipleOfSourceRowToDestinationRowButton  matlab.ui.control.Button
        MultiplyFactorEditField         matlab.ui.control.EditField
        MultiplyFactorEditFieldLabel    matlab.ui.control.Label
        RowToMultiplyEditField          matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label   matlab.ui.control.Label
        MultiplyRowButton               matlab.ui.control.Button
        RowSwap2EditField               matlab.ui.control.NumericEditField
        RowSwap2EditFieldLabel          matlab.ui.control.Label
        RowSwap1EditField               matlab.ui.control.NumericEditField
        RowSwap1EditFieldLabel          matlab.ui.control.Label
        SwapRowButton                   matlab.ui.control.Button
        FindNullityButton               matlab.ui.control.Button
        FindRankButton                  matlab.ui.control.Button
        FindNullSpaceBasisButton        matlab.ui.control.Button
        FindColSpaceBasisButton         matlab.ui.control.Button
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        RREFButton                      matlab.ui.control.Button
        GenerateMatrixButton            matlab.ui.control.Button
        FindRowSpaceBasisButton         matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        CheckSpanButton                 matlab.ui.control.Button
        CheckLinearlyIndependentButton  matlab.ui.control.Button
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        ColsEditField                   matlab.ui.control.NumericEditField
        ColsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        GenerateAugementedMatrixButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        SomeTab                         matlab.ui.container.Tab
    end

    methods (Access = private)

        % Set cols name for AugM matrix
        function columnNames = setAugMName(app, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols and rows name
            for i = 1:cols - 1
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
  
            % Last col is the vector b
            columnNames{end} = 'Vector b';
            
            % Set the row and col names
            app.UITable.ColumnName = columnNames;
            app.UITable.RowName = rowNames;
        end
        

        % Set cols name for Normal Matrix
        function columnNames = setMName(app, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols name
            for i = 1:cols
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
            
            % Set the row and col names
            app.UITable.ColumnName = columnNames;
            app.UITable.RowName = rowNames;
        end
        

        % Convert the string input to numeric (CHATGPT)
        function num = stringToNumeric(~, str)
            % Strip leading and trailing spaces
            str = strtrim(str);
            
            % Pattern for matching valid numbers, fractions, and decimal numbers (including negative values)
            validPattern = '^(-?\d+(\.\d+)?)(/\d+(\.\d+)?)?$';
            
            % Check if the string matches the valid pattern for numbers or fractions
            if ~isempty(regexp(str, validPattern, 'once'))
                if contains(str, '/')
                    parts = strsplit(str, '/');
                    numerator = str2double(parts{1});
                    denominator = str2double(parts{2});
                    if denominator ~= 0 % Ensure denominator is not zero for fractions
                        num = numerator / denominator;
                        return;
                    end
                else
                    num = str2double(str); % Direct conversion for whole and decimal numbers
                    return;
                end
            end
            
            % If input is not valid according to our criteria, return NaN
            num = NaN;
        end
        

        % Function to convert String Matrix to Numeric Matrix
        function numericMatrix = getNumericMatrix(app, StringMatrix)
            % Zeros matrix as a placeholder 
            numericMatrix = zeros(size(StringMatrix));
            
            % For loop to get all the elements
            for row = 1:size(StringMatrix, 1)
                for col = 1:size(StringMatrix, 2)
                    numericMatrix(row, col) = app.stringToNumeric(StringMatrix{row, col});
                end
            end
            
        end
        
        
        % Function to convert a matrix of doubles to a string matrix
        function stringMatrix = doubleToStringMatrix(~, doubleMatrix)
            % Initialize the string matrix with the same size as the double matrix
            stringMatrix = cell(size(doubleMatrix));
            
            % Loop through each element in the double matrix
            for row = 1:size(doubleMatrix, 1)
                for col = 1:size(doubleMatrix, 2)
                    % Convert each double element to a string and store in the corresponding position
                    stringMatrix{row, col} = num2str(doubleMatrix(row, col));
                end
            end
        end
        
        % Align the stuff in the center
        function centerAlign(app)
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable, s, 'table','');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Use to set up font and format stuff

            app.UITable.ColumnFormat = {'char'};
            app.centerAlign();
        end

        % Button pushed function: GenerateAugementedMatrixButton
        function GenerateAugementedMatrixButtonPushed(app, event)
            % Get number of rows and cols
            num_of_rows = app.RowsEditField.Value;
            num_of_cols = app.ColsEditField.Value;

            % Set name for cols and rows
            app.setAugMName(num_of_rows, num_of_cols);

            % Generate Zeros vector as string
            app.UITable.Data = string(zeros(num_of_rows, num_of_cols));
        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            % Get number of rows and cols
            num_of_rows = app.RowsEditField.Value;
            num_of_cols = app.ColsEditField.Value;

            % Set name for rows and cols
            app.setMName(num_of_rows, num_of_cols);

            % Generate a String Zeros vector on the Table as a placeholder
            app.UITable.Data = string(zeros(num_of_rows, num_of_cols));
        end

        % Button pushed function: CheckSpanButton
        function CheckSpanButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Code from Prof. Li CheckSpan.m
                [~ ,pb] = rref(AugM); % get RREF of the augmented matrix AugM
                A=AugM(:,1:end-1); % extract the coefficient matrix A from AugM;
                [~ ,p] = rref(A);
    
                if length(pb) ~= length(p) 
                    app.ResultsEditField.Value = "the vector b IS NOT IN the span of the given set of vectors";
                else
                    app.ResultsEditField.Value = "the vector b IS IN the span of the given set of vectors";
                end
            end
        end

        % Button pushed function: CheckLinearlyIndependentButton
        function CheckLinearlyIndependentButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Code from Prof. Li CheckLide.m
                [~,p] = rref(AugM);
    
                if length(p) == size (AugM,2) % size(A,1) gives #of rows in the matrix, and size(A,2) gives #of columns
                    app.ResultsEditField.Value = "This set of vectors is linearly INDEPENDENT!";
                else
                    app.ResultsEditField.Value = "This set of vectors is linearly DEPENDENT!";
                end
            end
        end

        % Button pushed function: RREFButton
        function RREFButtonPushed(app, event)
            % Get string Matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Compute the reduced row echelon form
                rref_A = rref(A);
                
                % Display the RREF in the second table with rational numbers
                app.UITable2.Data = rref_A; 
                
                % Set all columns to display as rational numbers {rat*num_cols}
                app.UITable2.ColumnFormat = repmat({'rat'}, 1, size(rref_A, 2));
            end
        end

        % Button pushed function: SolveAugementedMatrixButton
        function SolveAugementedMatrixButtonPushed(app, event)
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Work because unique sols only have one col to format
                app.UITable2.ColumnFormat = {'rat'};
                
                % Unique solution only
                matrix_A = AugM(:, 1:end-1);
                vector_b = AugM(:, end);
                
                % Solve Ax = b
                solutions = linsolve(matrix_A, vector_b);
                disp(solutions)
                
                % Display message
                app.ResultsEditField.Value = 'The unique solution set of Ax = b is: ';
    
                % Display the solutions
                app.UITable2.Data = solutions;
                
            end
        end

        % Button pushed function: FindRowSpaceBasisButton
        function FindRowSpaceBasisButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Find the Basis of Row Space - Prof. Li
                A=sym(A); 
                B = A';
                i = colspace(B);
                app.ResultsEditField.Value = "The basis of its ROW space is: ";
                app.UITable2.Data = double(i);
            end
        end

        % Button pushed function: FindColSpaceBasisButton
        function FindColSpaceBasisButtonPushed(app, event)
            % Get string matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                ii = colspace(A);
                app.ResultsEditField.Value = "The basis of its COLUMN space is: ";
                app.UITable2.Data = double(ii);
            end
        end

        % Button pushed function: FindNullSpaceBasisButton
        function FindNullSpaceBasisButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iii= colspace(null(A));
                app.ResultsEditField.Value = "The basis of its NULL space is: ";
                if isempty(iii)==0
                    app.UITable2.Data = double(iii);
                else
                    app.ResultsEditField.Value = "The basis of its NULL space is: ";
                    app.UITable2.Data = double([]);
                end
            end
        end

        % Button pushed function: FindRankButton
        function FindRankButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iv= rank(A);
                app.ResultsEditField.Value = 'The RANK of this matrix is: ' + string(iv);
            end
        end

        % Button pushed function: FindNullityButton
        function FindNullityButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iii= colspace(null(A));
                v= size(iii,2);
                app.ResultsEditField.Value = 'The NULLITY of this matrix is: ' + string(v);
            end
        end

        % Button pushed function: SwapRowButton
        function SwapRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                % Set orginial matrix as the output matrix if the user
                % first perform row operation
                if isempty(app.UITable.Data)
                    app.UITable.Data = M;
                end
    
                A = app.UITable.Data;
        
                row_swap_1 = app.RowSwap1EditField.Value;
                row_swap_2 = app.RowSwap2EditField.Value;
                
                B = A;
                B(row_swap_2,:) = A(row_swap_1,:);
                B(row_swap_1,:) = A(row_swap_2,:);
    
                % Update the matrix
                app.UITable.Data = B;
            end
        end

        % Button pushed function: MultiplyRowButton
        function MultiplyRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                row_to_multiply = app.RowToMultiplyEditField.Value;
                multiply_factor = eval(app.MultiplyFactorEditField.Value);
                
                B = M;
                B(row_to_multiply, :) = multiply_factor*M(row_to_multiply, :);
    
                % Update the matrix
                app.UITable.Data = app.doubleToStringMatrix(B);
            end
        end

        % Button pushed function: 
        % AddMultipleOfSourceRowToDestinationRowButton
        function AddMultipleOfSourceRowToDestinationRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));

                multiply_factor = eval(app.MultiplyFactorEditField_2.Value);
                source_row = app.SourceRowEditField.Value;
                destination_row = app.DestinationRowEditField.Value;
                
                B = M;
                B(destination_row,:) = multiply_factor*M(source_row,:) + M(destination_row,:);
    
                % Update the matrix
                app.UITable.Data = app.doubleToStringMatrix(B);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.UITable.Data(indices(1), indices(2)) = {'NaN'};
            end
        end

        % Button pushed function: PlotColVectorsButton
        function PlotColVectorsButtonPushed(app, event)
% Clear the axes for fresh plot
cla(app.UIAxes, 'reset');

set(app.UIFigure, 'Renderer', 'opengl');  % Ensure the figure uses OpenGL

% Get the matrix data from the UITable
matrixData = app.getNumericMatrix(app.UITable.Data);

% Check if the vectors are in 2D or 3D
R_dimension = size(matrixData, 1);

is2Dplane = size(app.UITable2.Data, 2) == 2;

if R_dimension == 3 && is2Dplane
    hold(app.UIAxes, 'on');

    basisMatrixData = app.UITable2.Data;

    v1 = basisMatrixData(:, 1);
    v2 = basisMatrixData(:, 2);

    % Create a grid of scalar coefficients (alpha and beta)
    [alpha, beta] = meshgrid(-1:0.1:1, -1:0.1:1);
    
    % Calculate the points on the plane spanned by v1 and v2
    X = alpha * v1(1) + beta * v2(1);
    Y = alpha * v1(2) + beta * v2(2);
    Z = alpha * v1(3) + beta * v2(3);
    
    % Plot the plane
    meshPlot = mesh(app.UIAxes, X, Y, Z);
    xlabel(app.UIAxes, 'X-axis');
    ylabel(app.UIAxes, 'Y-axis');
    zlabel(app.UIAxes, 'Z-axis');
    title(app.UIAxes, '2D Plane Spanned by Two 3D Vectors');

    % Set the transparency of the mesh plot
    set(meshPlot, 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5);

    for i = 1:size(matrixData, 2)
        % Extract the vector
        vec = matrixData(:,i);
        % Normalize the vector by 2 * unit vector 
        norm_vec = vec*2 / norm(vec);
        % Plot 3D normalized vectors with thicker lines
        quiver3(app.UIAxes, 0, 0, 0, norm_vec(1), norm_vec(2), norm_vec(3), ...
                'LineWidth', 2, 'Color', 'r', 'AutoScale', 'off');
    end
    view(app.UIAxes, 3);

    app.UITable3.Data = string(zeros(3, 1));

    title(app.UIAxes, 'Span of Column Space');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z'); 
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
else
    uialert(app.UIFigure, 'This can only visulize 2D Subspace in 3D. Row/Col Basis should only have 2 3-D vectors', 'Input Error');
end
        end

        % Button pushed function: PlotPlaneInR3Button
        function PlotPlaneInR3ButtonPushed(app, event)
            cla(app.UIAxes, 'reset')

            set(app.UIFigure, 'Renderer', 'opengl');  % Ensure the figure uses OpenGL
            
            hold(app.UIAxes, 'on');

            matrixData = app.UITable2.Data;

            v1 = matrixData(:, 1);
            v2 = matrixData(:, 2);

            % Create a grid of scalar coefficients (alpha and beta)
            [alpha, beta] = meshgrid(-1:0.1:1, -1:0.1:1);
            
            % Calculate the points on the plane spanned by v1 and v2
            X = alpha * v1(1) + beta * v2(1);
            Y = alpha * v1(2) + beta * v2(2);
            Z = alpha * v1(3) + beta * v2(3);
            
            % Plot the plane
            meshPlot = mesh(app.UIAxes, X, Y, Z);
            xlabel(app.UIAxes, 'X-axis');
            ylabel(app.UIAxes, 'Y-axis');
            zlabel(app.UIAxes, 'Z-axis');
            title(app.UIAxes, '2D Plane Spanned by Two 3D Vectors');

            % Set the transparency of the mesh plot
            set(meshPlot, 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5);

            view(app.UIAxes, 3);
        end

        % Button pushed function: PlotCheckButton
        function PlotCheckButtonPushed(app, event)
            matrixData = app.getNumericMatrix(app.UITable3.Data);
            % TODO: if in span plot orange, if not plot blue.

                vec = matrixData(:,1);
                % Normalize the vector by 2 * unit vector 
                norm_vec = vec*2 / norm(vec);
                % Plot 3D normalized vectors with thicker lines
                quiver3(app.UIAxes, 0, 0, 0, norm_vec(1), norm_vec(2), norm_vec(3), ...
                        'LineWidth', 2, 'Color', 'r', 'AutoScale', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.UIAxes = uiaxes(app.TESTTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.TESTTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.GenerateAugementedMatrixButton = uibutton(app.TESTTab, 'push');
            app.GenerateAugementedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAugementedMatrixButtonPushed, true);
            app.GenerateAugementedMatrixButton.Text = 'GenerateAugementedMatrix';
            app.RowsEditFieldLabel = uilabel(app.TESTTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ColsEditFieldLabel = uilabel(app.TESTTab);
            app.ColsEditFieldLabel.Text = 'Cols';
            app.ColsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ResultsEditFieldLabel = uilabel(app.TESTTab);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.TESTTab, 'text');
            app.CheckLinearlyIndependentButton = uibutton(app.TESTTab, 'push');
            app.CheckLinearlyIndependentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLinearlyIndependentButtonPushed, true);
            app.CheckLinearlyIndependentButton.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButton = uibutton(app.TESTTab, 'push');
            app.CheckSpanButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSpanButtonPushed, true);
            app.CheckSpanButton.Text = 'CheckSpan';
            app.UITable2 = uitable(app.TESTTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable2.RowName = {};
            app.FindRowSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindRowSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindRowSpaceBasisButtonPushed, true);
            app.FindRowSpaceBasisButton.Text = 'FindRowSpaceBasis';
            app.GenerateMatrixButton = uibutton(app.TESTTab, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'GenerateMatrix';
            app.RREFButton = uibutton(app.TESTTab, 'push');
            app.RREFButton.ButtonPushedFcn = createCallbackFcn(app, @RREFButtonPushed, true);
            app.RREFButton.Text = 'RREF';
            app.InstructionsTextAreaLabel = uilabel(app.TESTTab);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.TESTTab);
            app.InstructionsTextArea.Value = {'1. Input number of rows and and number of cols of the input box.'; '2. Generate a 0s matrix '; '3. Input number by hand'; '4. Use button below to check attribute'};
            app.FindColSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindColSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindColSpaceBasisButtonPushed, true);
            app.FindColSpaceBasisButton.Text = 'FindColSpaceBasis';
            app.FindNullSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindNullSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullSpaceBasisButtonPushed, true);
            app.FindNullSpaceBasisButton.Text = 'FindNullSpaceBasis';
            app.FindRankButton = uibutton(app.TESTTab, 'push');
            app.FindRankButton.ButtonPushedFcn = createCallbackFcn(app, @FindRankButtonPushed, true);
            app.FindRankButton.Text = 'FindRank';
            app.FindNullityButton = uibutton(app.TESTTab, 'push');
            app.FindNullityButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullityButtonPushed, true);
            app.FindNullityButton.Text = 'FindNullity';
            app.SwapRowButton = uibutton(app.TESTTab, 'push');
            app.SwapRowButton.ButtonPushedFcn = createCallbackFcn(app, @SwapRowButtonPushed, true);
            app.SwapRowButton.Text = 'SwapRow';
            app.RowSwap1EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap1EditFieldLabel.Text = 'RowSwap1';
            app.RowSwap1EditField = uieditfield(app.TESTTab, 'numeric');
            app.RowSwap2EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap2EditFieldLabel.Text = 'RowSwap2';
            app.RowSwap2EditField = uieditfield(app.TESTTab, 'numeric');
            app.MultiplyRowButton = uibutton(app.TESTTab, 'push');
            app.MultiplyRowButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyRowButtonPushed, true);
            app.MultiplyRowButton.Text = 'MultiplyRow';
            app.RowToMultiplyEditField_2Label = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label.Text = 'RowToMultiply';
            app.RowToMultiplyEditField = uieditfield(app.TESTTab, 'numeric');
            app.MultiplyFactorEditFieldLabel = uilabel(app.TESTTab);
            app.MultiplyFactorEditFieldLabel.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField.Value = '0';
            app.AddMultipleOfSourceRowToDestinationRowButton = uibutton(app.TESTTab, 'push');
            app.AddMultipleOfSourceRowToDestinationRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddMultipleOfSourceRowToDestinationRowButtonPushed, true);
            app.AddMultipleOfSourceRowToDestinationRowButton.Text = 'AddMultipleOfSourceRowToDestinationRow';
            app.MultiplyFactorEditField_2Label = uilabel(app.TESTTab);
            app.MultiplyFactorEditField_2Label.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField_2 = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField_2.Value = '0';
            app.RowToMultiplyEditField_2Label_2 = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label_2.Text = 'SourceRow';
            app.SourceRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.DestinationRowEditFieldLabel = uilabel(app.TESTTab);
            app.DestinationRowEditFieldLabel.Text = 'DestinationRow';
            app.DestinationRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.SolveAugementedMatrixButton = uibutton(app.TESTTab, 'push');
            app.SolveAugementedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SolveAugementedMatrixButtonPushed, true);
            app.SolveAugementedMatrixButton.Text = 'SolveAugementedMatrix';
            app.PlotColVectorsButton = uibutton(app.TESTTab, 'push');
            app.PlotColVectorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotColVectorsButtonPushed, true);
            app.PlotColVectorsButton.Text = 'PlotColVectors';
            app.PlotPlaneInR3Button = uibutton(app.TESTTab, 'push');
            app.PlotPlaneInR3Button.ButtonPushedFcn = createCallbackFcn(app, @PlotPlaneInR3ButtonPushed, true);
            app.PlotPlaneInR3Button.Text = 'PlotPlaneInR3';
            app.UITable3 = uitable(app.TESTTab);
            app.UITable3.ColumnName = {'Column 1'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.PlotCheckButton = uibutton(app.TESTTab, 'push');
            app.PlotCheckButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCheckButtonPushed, true);
            app.PlotCheckButton.Text = 'PlotCheck';
            app.SomeTab = uitab(app.TabGroup);
            app.SomeTab.Title = 'SomeTab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_Huy_April11_v6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end