classdef linear_Huy_March22_1am_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TESTTab                         matlab.ui.container.Tab
        ErrorEditField                  matlab.ui.control.EditField
        ErrorEditFieldLabel             matlab.ui.control.Label
        SolveAugementedMatrixButton     matlab.ui.control.Button
        DestinationRowEditField         matlab.ui.control.NumericEditField
        DestinationRowEditFieldLabel    matlab.ui.control.Label
        SourceRowEditField              matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label_2  matlab.ui.control.Label
        MultiplyFactorEditField_2       matlab.ui.control.EditField
        MultiplyFactorEditField_2Label  matlab.ui.control.Label
        AddMultipleOfSourceRowToDestinationRowButton  matlab.ui.control.Button
        MultiplyFactorEditField         matlab.ui.control.EditField
        MultiplyFactorEditFieldLabel    matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        RowToMultiplyEditField          matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label   matlab.ui.control.Label
        MultiplyRowButton               matlab.ui.control.Button
        RowSwap2EditField               matlab.ui.control.NumericEditField
        RowSwap2EditFieldLabel          matlab.ui.control.Label
        RowSwap1EditField               matlab.ui.control.NumericEditField
        RowSwap1EditFieldLabel          matlab.ui.control.Label
        SwapRowButton                   matlab.ui.control.Button
        FindNullityButton               matlab.ui.control.Button
        FindRankButton                  matlab.ui.control.Button
        FindNullSpaceBasisButton        matlab.ui.control.Button
        FindColSpaceBasisButton         matlab.ui.control.Button
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        RREFButton                      matlab.ui.control.Button
        GenerateMatrixButton            matlab.ui.control.Button
        FindRowSpaceBasisButton         matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        CheckSpanButton                 matlab.ui.control.Button
        CheckLinearlyIndependentButton  matlab.ui.control.Button
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        ColsEditField                   matlab.ui.control.NumericEditField
        ColsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        GenerateAugementedMatrixButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SomeTab                         matlab.ui.container.Tab
    end

    methods (Access = private)

        % Set cols name for AugM matrix
        function columnNames = setAugMName(app, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols and rows name
            for i = 1:cols - 1
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
  
            % Last col is the vector b
            columnNames{end} = 'Vector b';
            
            % Set the row and col names
            app.UITable.ColumnName = columnNames;
            app.UITable.RowName = rowNames;
        end
        
        % Set cols name for Normal Matrix
        function columnNames = setMName(app, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols name
            for i = 1:cols
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
            
            % Set the row and col names
            app.UITable.ColumnName = columnNames;
            app.UITable.RowName = rowNames;
        end
        
        % Convert the string input to numeric (CHATGPT)
        function num = stringToNumeric(~, str)
            % Strip leading and trailing spaces
            str = strtrim(str);
            
            % Pattern for matching valid numbers, fractions, and decimal numbers (including negative values)
            validPattern = '^(-?\d+(\.\d+)?)(/\d+(\.\d+)?)?$';
            
            % Check if the string matches the valid pattern for numbers or fractions
            if ~isempty(regexp(str, validPattern, 'once'))
                if contains(str, '/')
                    parts = strsplit(str, '/');
                    numerator = str2double(parts{1});
                    denominator = str2double(parts{2});
                    if denominator ~= 0 % Ensure denominator is not zero for fractions
                        num = numerator / denominator;
                        return;
                    end
                else
                    num = str2double(str); % Direct conversion for whole and decimal numbers
                    return;
                end
            end
            
            % If input is not valid according to our criteria, return NaN
            num = NaN;
        end
        
        % Function to convert String Matrix to Numeric Matrix
        function numericMatrix = getNumericMatrix(app, StringMatrix)
            % Zeros matrix as a placeholder 
            numericMatrix = zeros(size(StringMatrix));
            
            % For loop to get all the elements
            for row = 1:size(StringMatrix, 1)
                for col = 1:size(StringMatrix, 2)
                    numericMatrix(row, col) = app.stringToNumeric(StringMatrix{row, col});
                end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateAugementedMatrixButton
        function GenerateAugementedMatrixButtonPushed(app, event)
            % Set the matrix input type as char
            app.UITable.ColumnFormat = {'char'};

            % Get number of rows and cols
            num_of_rows = app.RowsEditField.Value;
            num_of_cols = app.ColsEditField.Value;

            % Set name for cols and rows
            app.setAugMName(num_of_rows, num_of_cols);

            % Generate Zeros vector as string
            app.UITable.Data = string(zeros(num_of_rows, num_of_cols));
        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            % Set the matrix input type as char
            app.UITable.ColumnFormat = {'char'};

            % Get number of rows and cols
            num_of_rows = app.RowsEditField.Value;
            num_of_cols = app.ColsEditField.Value;

            % Set name for rows and cols
            app.setMName(num_of_rows, num_of_cols);

            % Generate a String Zeros vector on the Table as a placeholder
            app.UITable.Data = string(zeros(num_of_rows, num_of_cols));
        end

        % Button pushed function: CheckSpanButton
        function CheckSpanButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                app.ErrorEditField.Value = 'Error: The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                % Code from Prof. Li CheckSpan.m
                [~ ,pb] = rref(AugM); % get RREF of the augmented matrix AugM
                A=AugM(:,1:end-1); % extract the coefficient matrix A from AugM;
                [~ ,p] = rref(A);
    
                if length(pb) ~= length(p) 
                    app.ResultsEditField.Value = "the vector b IS NOT IN the span of the given set of vectors";
                else
                    app.ResultsEditField.Value = "the vector b IS IN the span of the given set of vectors";
                end
            end
        end

        % Button pushed function: CheckLinearlyIndependentButton
        function CheckLinearlyIndependentButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                % Code from Prof. Li CheckLide.m
                [~,p] = rref(AugM);
    
                if length(p) == size (AugM,2) % size(A,1) gives #of rows in the matrix, and size(A,2) gives #of columns
                    app.ResultsEditField.Value = "This set of vectors is linearly INDEPENDENT!";
                else
                    app.ResultsEditField.Value = "This set of vectors is linearly DEPENDENT!";
                end
            end
        end

        % Button pushed function: RREFButton
        function RREFButtonPushed(app, event)
            % Get string Matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                % Compute the reduced row echelon form
                rref_A = rref(A);
                
                % Display the RREF in the second table with rational numbers
                app.UITable2.Data = rref_A; 
                
                % Set all columns to display as rational numbers {rat*num_cols}
                app.UITable2.ColumnFormat = repmat({'rat'}, 1, size(rref_A, 2));
            end
        end

        % Button pushed function: SolveAugementedMatrixButton
        function SolveAugementedMatrixButtonPushed(app, event)
            AugM = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                % Work because unique sols only have one col to format
                app.UITable2.ColumnFormat = {'rat'};
                
                % Unique solution only
                matrix_A = AugM(:, 1:end-1);
                vector_b = AugM(:, end);
                
                % Solve Ax = b
                solutions = linsolve(matrix_A, vector_b);
                disp(solutions)
                
                % Display message
                app.ResultsEditField.Value = 'The unique solution set of Ax = b is: ';
    
                % Display the solutions
                app.UITable2.Data = solutions;
                
            end
        end

        % Button pushed function: FindRowSpaceBasisButton
        function FindRowSpaceBasisButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                % Find the Basis of Row Space - Prof. Li
                A=sym(A); 
                B = A';
                i = colspace(B);
                app.ResultsEditField.Value = "The basis of its ROW space is: ";
                app.UITable2.Data = double(i);
            end
        end

        % Button pushed function: FindColSpaceBasisButton
        function FindColSpaceBasisButtonPushed(app, event)
            % Get string matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                A=sym(A); 
                ii = colspace(A);
                app.ResultsEditField.Value = "The basis of its COLUMN space is: ";
                app.UITable2.Data = double(ii);
            end
        end

        % Button pushed function: FindNullSpaceBasisButton
        function FindNullSpaceBasisButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                A=sym(A); 
                iii= colspace(null(A));
                app.ResultsEditField.Value = "The basis of its NULL space is: ";
                if isempty(iii)==0
                    app.UITable2.Data = double(iii);
                else
                    app.ResultsEditField.Value = "The basis of its NULL space is: ";
                    app.UITable2.Data = double([]);
                end
            end
        end

        % Button pushed function: FindRankButton
        function FindRankButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                A=sym(A); 
                iv= rank(A);
                app.ResultsEditField.Value = 'The RANK of this matrix is: ' + string(iv);
            end
        end

        % Button pushed function: FindNullityButton
        function FindNullityButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                A=sym(A); 
                iii= colspace(null(A));
                v= size(iii,2);
                app.ResultsEditField.Value = 'The NULLITY of this matrix is: ' + string(v);
            end
        end

        % Button pushed function: SwapRowButton
        function SwapRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                app.UITable3.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                % Set orginial matrix as the output matrix if the user
                % first perform row operation
                if isempty(app.UITable3.Data)
                    app.UITable3.Data = M;
                end
    
                A = app.UITable3.Data;
        
                row_swap_1 = app.RowSwap1EditField.Value;
                row_swap_2 = app.RowSwap2EditField.Value;
                
                B = A;
                B(row_swap_2,:) = A(row_swap_1,:);
                B(row_swap_1,:) = A(row_swap_2,:);
    
                % Update the matrix
                app.UITable3.Data = B;
            end
        end

        % Button pushed function: MultiplyRowButton
        function MultiplyRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                app.UITable3.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                % Set orginial matrix as the output matrix if the user
                % first perform row operation
                if isempty(app.UITable3.Data)
                    app.UITable3.Data = M;
                end
    
                A = app.UITable3.Data;
                
                row_to_multiply = app.RowToMultiplyEditField.Value;
                multiply_factor = eval(app.MultiplyFactorEditField.Value);
                
                B = A;
                B(row_to_multiply, :) = multiply_factor*A(row_to_multiply, :);
    
                % Update the matrix
                app.UITable3.Data = B;
            end
        end

        % Button pushed function: 
        % AddMultipleOfSourceRowToDestinationRowButton
        function AddMultipleOfSourceRowToDestinationRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                app.ErrorEditField.Value = 'The matrix contains at least one NaN value';
            else
                app.ErrorEditField.Value = 'No Error: Run Fine';
                app.UITable3.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                % Set orginial matrix as the output matrix if the user
                % first perform row operation
                if isempty(app.UITable3.Data)
                    app.UITable3.Data = M;
                end
                
                A = app.UITable3.Data;
        
                multiply_factor = eval(app.MultiplyFactorEditField_2.Value);
                source_row = app.SourceRowEditField.Value;
                destination_row = app.DestinationRowEditField.Value;
                
                B = A;
                B(destination_row,:) = multiply_factor*A(source_row,:) + A(destination_row,:);
    
                % Update the matrix
                app.UITable3.Data = B;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.UITable.Data(indices(1), indices(2)) = {'NaN'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.UITable = uitable(app.TESTTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.GenerateAugementedMatrixButton = uibutton(app.TESTTab, 'push');
            app.GenerateAugementedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAugementedMatrixButtonPushed, true);
            app.GenerateAugementedMatrixButton.Text = 'GenerateAugementedMatrix';
            app.RowsEditFieldLabel = uilabel(app.TESTTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ColsEditFieldLabel = uilabel(app.TESTTab);
            app.ColsEditFieldLabel.Text = 'Cols';
            app.ColsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ResultsEditFieldLabel = uilabel(app.TESTTab);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.TESTTab, 'text');
            app.CheckLinearlyIndependentButton = uibutton(app.TESTTab, 'push');
            app.CheckLinearlyIndependentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLinearlyIndependentButtonPushed, true);
            app.CheckLinearlyIndependentButton.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButton = uibutton(app.TESTTab, 'push');
            app.CheckSpanButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSpanButtonPushed, true);
            app.CheckSpanButton.Text = 'CheckSpan';
            app.UITable2 = uitable(app.TESTTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable2.RowName = {};
            app.FindRowSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindRowSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindRowSpaceBasisButtonPushed, true);
            app.FindRowSpaceBasisButton.Text = 'FindRowSpaceBasis';
            app.GenerateMatrixButton = uibutton(app.TESTTab, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'GenerateMatrix';
            app.RREFButton = uibutton(app.TESTTab, 'push');
            app.RREFButton.ButtonPushedFcn = createCallbackFcn(app, @RREFButtonPushed, true);
            app.RREFButton.Text = 'RREF';
            app.InstructionsTextAreaLabel = uilabel(app.TESTTab);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.TESTTab);
            app.InstructionsTextArea.Value = {'1. Input number of rows and and number of cols of the input box.'; '2. Generate a 0s matrix '; '3. Input number by hand'; '4. Use button below to check attribute'};
            app.FindColSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindColSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindColSpaceBasisButtonPushed, true);
            app.FindColSpaceBasisButton.Text = 'FindColSpaceBasis';
            app.FindNullSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindNullSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullSpaceBasisButtonPushed, true);
            app.FindNullSpaceBasisButton.Text = 'FindNullSpaceBasis';
            app.FindRankButton = uibutton(app.TESTTab, 'push');
            app.FindRankButton.ButtonPushedFcn = createCallbackFcn(app, @FindRankButtonPushed, true);
            app.FindRankButton.Text = 'FindRank';
            app.FindNullityButton = uibutton(app.TESTTab, 'push');
            app.FindNullityButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullityButtonPushed, true);
            app.FindNullityButton.Text = 'FindNullity';
            app.SwapRowButton = uibutton(app.TESTTab, 'push');
            app.SwapRowButton.ButtonPushedFcn = createCallbackFcn(app, @SwapRowButtonPushed, true);
            app.SwapRowButton.Text = 'SwapRow';
            app.RowSwap1EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap1EditFieldLabel.Text = 'RowSwap1';
            app.RowSwap1EditField = uieditfield(app.TESTTab, 'numeric');
            app.RowSwap2EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap2EditFieldLabel.Text = 'RowSwap2';
            app.RowSwap2EditField = uieditfield(app.TESTTab, 'numeric');
            app.MultiplyRowButton = uibutton(app.TESTTab, 'push');
            app.MultiplyRowButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyRowButtonPushed, true);
            app.MultiplyRowButton.Text = 'MultiplyRow';
            app.RowToMultiplyEditField_2Label = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label.Text = 'RowToMultiply';
            app.RowToMultiplyEditField = uieditfield(app.TESTTab, 'numeric');
            app.UITable3 = uitable(app.TESTTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.MultiplyFactorEditFieldLabel = uilabel(app.TESTTab);
            app.MultiplyFactorEditFieldLabel.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField.Value = '0';
            app.AddMultipleOfSourceRowToDestinationRowButton = uibutton(app.TESTTab, 'push');
            app.AddMultipleOfSourceRowToDestinationRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddMultipleOfSourceRowToDestinationRowButtonPushed, true);
            app.AddMultipleOfSourceRowToDestinationRowButton.Text = 'AddMultipleOfSourceRowToDestinationRow';
            app.MultiplyFactorEditField_2Label = uilabel(app.TESTTab);
            app.MultiplyFactorEditField_2Label.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField_2 = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField_2.Value = '0';
            app.RowToMultiplyEditField_2Label_2 = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label_2.Text = 'SourceRow';
            app.SourceRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.DestinationRowEditFieldLabel = uilabel(app.TESTTab);
            app.DestinationRowEditFieldLabel.Text = 'DestinationRow';
            app.DestinationRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.SolveAugementedMatrixButton = uibutton(app.TESTTab, 'push');
            app.SolveAugementedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SolveAugementedMatrixButtonPushed, true);
            app.SolveAugementedMatrixButton.Text = 'SolveAugementedMatrix';
            app.ErrorEditFieldLabel = uilabel(app.TESTTab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.TESTTab, 'text');
            app.SomeTab = uitab(app.TabGroup);
            app.SomeTab.Title = 'SomeTab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_Huy_March22_1am_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end