classdef linear_Huy_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CHECKTab                        matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        CheckSpanButton                 matlab.ui.control.Button
        CheckLinearlyIndependentButton  matlab.ui.control.Button
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        ColEditField                    matlab.ui.control.NumericEditField
        ColEditFieldLabel               matlab.ui.control.Label
        RowEditField                    matlab.ui.control.NumericEditField
        RowEditFieldLabel               matlab.ui.control.Label
        GenerateMatrixButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SomeTab                         matlab.ui.container.Tab
    end

    methods (Access = private)
        % Function to set the column names
        function columnNames = setColumnName(app, n)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, n);

            % Simple for loop to get cols name
            for i = 1:n-1
                columnNames{i} = ['Vector ' num2str(i)];
            end
            % Last col is the vector b
            columnNames{end} = 'Vector b';
            
            % Set the col names
            app.UITable.ColumnName = columnNames;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            num_of_rows = app.RowEditField.Value;
            num_of_cols = app.ColEditField.Value;
            app.setColumnName(num_of_cols);
            app.UITable.Data = zeros(num_of_rows, num_of_cols);
        end

        % Button pushed function: CheckSpanButton
        function CheckSpanButtonPushed(app, event)
            % Get the input matrix from the user
            AugM = app.UITable.Data;

            % Code from Prof. Li CheckSpan.m
            [Rb,pb] = rref(AugM); % get RREF of the augmented matrix AugM
            A=AugM(:,1:end-1); % extract the coefficient matrix A from AugM;
            [R,p] = rref(A);

            if length(pb) ~= length(p) 
                app.ResultsEditField.Value = "the vector b IS NOT IN the span of the given set of vectors";
            else
                app.ResultsEditField.Value = "the vector b IS IN the span of the given set of vectors";
            end
        end

        % Button pushed function: CheckLinearlyIndependentButton
        function CheckLinearlyIndependentButtonPushed(app, event)
            AugM = app.UITable.Data;
            [R,p] = rref(AugM);

            if length(p) == size (AugM,2) % size(A,1) gives #of rows in the matrix, and size(A,2) gives #of columns
                app.ResultsEditField.Value = "This set of vectors is linearly INDEPENDENT!";
            else
                app.ResultsEditField.Value = "This set of vectors is linearly DEPENDENT!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CHECKTab = uitab(app.TabGroup);
            app.CHECKTab.Title = 'CHECK';
            app.UITable = uitable(app.CHECKTab);
            app.UITable.ColumnName = {'Vector 1'; 'Vector 2'; 'Vector b'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.GenerateMatrixButton = uibutton(app.CHECKTab, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'GenerateMatrix';
            app.RowEditFieldLabel = uilabel(app.CHECKTab);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.CHECKTab, 'numeric');
            app.ColEditFieldLabel = uilabel(app.CHECKTab);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.CHECKTab, 'numeric');
            app.ResultsEditFieldLabel = uilabel(app.CHECKTab);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.CHECKTab, 'text');
            app.CheckLinearlyIndependentButton = uibutton(app.CHECKTab, 'push');
            app.CheckLinearlyIndependentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLinearlyIndependentButtonPushed, true);
            app.CheckLinearlyIndependentButton.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButton = uibutton(app.CHECKTab, 'push');
            app.CheckSpanButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSpanButtonPushed, true);
            app.CheckSpanButton.Text = 'CheckSpan';
            app.HTML = uihtml(app.CHECKTab);
            app.HTML.HTMLSource = 'test4.html';
            app.SomeTab = uitab(app.TabGroup);
            app.SomeTab.Title = 'SomeTab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_Huy_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end