classdef sqrtHeron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextTextArea               matlab.ui.control.TextArea
        TextTextAreaLabel          matlab.ui.control.Label
        OutputEditField            matlab.ui.control.NumericEditField
        OutputEditFieldLabel       matlab.ui.control.Label
        InputNumberEditField       matlab.ui.control.NumericEditField
        InputNumberEditFieldLabel  matlab.ui.control.Label
        CalculateSqrtButton        matlab.ui.control.Button
    end

    properties (Access = private)
        Text % Description
    end
    
    methods (Access = private)  
        function x = sqrtHeron(~, S)
            if (S == 0)
                x = 0;
                return;
            end 
            % Choose a suitable initial guess
            xold = S/2;
            x = mean([xold S/xold]);
            
            % Repeat until you obtain a good enough root
            while abs(x - xold) > 0.01
                xold = x;
                x = mean([x S/x]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateSqrtButton
        function CalculateSqrtButtonPushed(app, event)
            if (app.InputNumberEditField.Value < 0)
                app.OutputEditField.Value = -1;
                app.TextTextArea.Value = "Invalid Input!";
            else
                app.OutputEditField.Value = app.sqrtHeron(app.InputNumberEditField.Value);
                app.TextTextArea.Value = "Square root of " + num2str(app.InputNumberEditField.Value) + " using Heron is " + num2str(app.OutputEditField.Value);
            end        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateSqrtButton = uibutton(app.UIFigure, 'push');
            app.CalculateSqrtButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSqrtButtonPushed, true);
            app.CalculateSqrtButton.Icon = fullfile(pathToMLAPP, 'Square_root_symbol.svg.png');
            app.CalculateSqrtButton.Text = 'Calculate Sqrt';
            app.InputNumberEditFieldLabel = uilabel(app.UIFigure);
            app.InputNumberEditFieldLabel.Text = 'Input Number';
            app.InputNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextTextAreaLabel = uilabel(app.UIFigure);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sqrtHeron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end