classdef vector_field_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        QuickStartGuideTextArea       matlab.ui.control.TextArea
        QuickStartGuideTextAreaLabel  matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        UpperBoundEditField           matlab.ui.control.NumericEditField
        UpperBoundEditFieldLabel      matlab.ui.control.Label
        LowerBoundEditField           matlab.ui.control.NumericEditField
        LowerBoundEditFieldLabel      matlab.ui.control.Label
        ZcomponentEditField           matlab.ui.control.EditField
        ZcomponentEditFieldLabel      matlab.ui.control.Label
        PlotVectorFieldButton         matlab.ui.control.Button
        YcomponentEditField           matlab.ui.control.EditField
        YcomponentEditFieldLabel      matlab.ui.control.Label
        XcomponentEditField           matlab.ui.control.EditField
        XcomponentEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
%     methods (Access = private)
%         
%         function [matrix_x_output, matrix_y_output, matrix_z_output] = convert_vector(~, ...
%                 matrix_x, matrix_y, matrix_z, x_expr, y_expr, z_expr)
% 
%             [rows, cols, heights] = size(matrix_x);
%             matrix_x_output = zeros(rows, cols, heights);
%             matrix_y_output = zeros(rows, cols, heights);
%             matrix_z_output = zeros(rows, cols, heights);
%             
%             for i = 1:rows
%                 for j = 1:cols
%                     for k = 1:heights
%                         % Set x, y, z = to the corresponding element to use eval
%                         x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
%     
%                         % Use eval on the x, y and z element above
%                         if (x_expr ~= "")
%                             matrix_x_output(i,j,k) = eval(x_expr);
%                         end
% 
%                         if (y_expr ~= "")
%                             matrix_y_output(i,j,k) = eval(y_expr);
%                         end
% 
%                         if (z_expr ~= "")
%                            matrix_z_output(i,j,k) = eval(z_expr);
%                         end
%                     end 
%                 end
%             end
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotVectorFieldButton
        function PlotVectorFieldButtonPushed(app, event)
            % Note: Will add some function calling for comprehension and clean
            
            
            % Read lower and upper bound
            lower_bound = app.LowerBoundEditField.Value;
            upper_bound = app.UpperBoundEditField.Value;
            
            % If lower_bound and upper_bound is divisible by the range
            % maybe change the range by something but still symmetrical
            % to avoid divided by 0
            % will update soon ...

            [matrix_x ,matrix_y, matrix_z] = meshgrid(lower_bound:1:upper_bound);
            
            % Convert User's input from char to string in the text field 
            x_expr = convertCharsToStrings(app.XcomponentEditField.Value);
            y_expr = convertCharsToStrings(app.YcomponentEditField.Value);
            z_expr = convertCharsToStrings(app.ZcomponentEditField.Value);
            
            
            % Create zeros array to store the results
            [rows, cols, heights] = size(matrix_x);
            matrix_x_output = zeros(rows, cols, heights);
            matrix_y_output = zeros(rows, cols, heights);
            matrix_z_output = zeros(rows, cols, heights);
            
            % Loop through each element in the array
            for i = 1:rows
                for j = 1:cols
                    for k = 1:heights
                        % Set x, y, z = to the corresponding element to use eval
                        x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
    
                        % Use eval on the x, y and z element above
                        if (x_expr ~= "")
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end

                        if (y_expr ~= "")
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end

                        if (z_expr ~= "")
                           matrix_z_output(i,j,k) = eval(z_expr);
                        end
                    end 
                end
            end
            
            % Color stuff
            if (app.ColorDropDown.Value == "red")
                color = "r";
            elseif (app.ColorDropDown.Value == "blue")
                color = "b";
            elseif (app.ColorDropDown.Value == "green")
                color = "g";
            elseif (app.ColorDropDown.Value == "cyan")
                color = "c";
            elseif (app.ColorDropDown.Value == "purple")
                color = "m";
            elseif (app.ColorDropDown.Value == "yellow")
                color = "y";
            elseif (app.ColorDropDown.Value == "black")
                color = "k";
            end
            
            % If the user is not inputting x, or y, or z, 
            % will plot 2D and 3D correspondingly maybe by doing 
            % if (z_expr ~= ""): [matrix_x ,matrix_y] = meshgrid(lower_bound:1:upper_bound);
            % to reduce the dimension 
            % will update soon ...

            % Plot
            if (z_expr ~= "")
               quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
                   matrix_x_output, matrix_y_output, matrix_z_output, 1.5, color)
            else
                quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 1.5, color)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector Field Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.XcomponentEditFieldLabel.Text = 'X-component';
            app.XcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.YcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.YcomponentEditFieldLabel.Text = 'Y-component';
            app.YcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.PlotVectorFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotVectorFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVectorFieldButtonPushed, true);
            app.PlotVectorFieldButton.Text = 'Plot Vector Field';
            app.ZcomponentEditFieldLabel = uilabel(app.UIFigure);
            app.ZcomponentEditFieldLabel.Text = 'Z-component';
            app.ZcomponentEditField = uieditfield(app.UIFigure, 'text');
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerBoundEditField.Value = -3;
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'Upper Bound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperBoundEditField.Value = 3;
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'blue', 'green', 'cyan', 'purple', 'yellow', 'black'};
            app.ColorDropDown.Value = 'red';
            app.QuickStartGuideTextAreaLabel = uilabel(app.UIFigure);
            app.QuickStartGuideTextAreaLabel.Text = 'Quick Start Guide';
            app.QuickStartGuideTextArea = uitextarea(app.UIFigure);
            app.QuickStartGuideTextArea.Value = {'1. Use * or / for multiplication, like 2*x or 2/x.'; ''; '2. Adjust bounds numerically for different views.'; '(lower bound should be smaller than upper bound)'; ''; '3. Use x, y, and z as variables. (will update another variable expression soon)'; ''; '4. Drag the Vector Field Plot to change viewing perspective.'; ''; '5. For 2D vectors, omit Z-component (r = <x, y>).'; ''; '6. When plotting some Division Expression as X/Y, adjust the bounds to some irrational numbers '; '(from -2.789 to 2.789)'; 'to avoid dividing by zeros  as the vector field plot will not show anything (will fix soon).'; ''; 'Check for more information, guides, demos, and updates at: https://github.com/Ai4Math/MATLAB-GUI'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vector_field_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end